classdef nivaaran < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        COVID19DataVisualisationUIFigure  matlab.ui.Figure
        CountryListBoxLabel          matlab.ui.control.Label
        CountryListBox               matlab.ui.control.ListBox
        StateorRegionLabel           matlab.ui.control.Label
        StateorRegionListBox         matlab.ui.control.ListBox
        AverageofdaysLabel           matlab.ui.control.Label
        AverageofdaysSlider          matlab.ui.control.Slider
        OptionButtonGroup            matlab.ui.container.ButtonGroup
        CumulativeButton             matlab.ui.control.RadioButton
        DailyButton                  matlab.ui.control.RadioButton
        DatatoPlotButtonGroup        matlab.ui.container.ButtonGroup
        CasesButton                  matlab.ui.control.RadioButton
        DeathButton                  matlab.ui.control.RadioButton
        BothButton                   matlab.ui.control.RadioButton
        SearchtheCountryEditField    matlab.ui.control.EditField
        SearhthestatevalueEditField  matlab.ui.control.EditField
        SearchLabel                  matlab.ui.control.Label
        SearchLabel_2                matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Global;
        Country; % Description
        States;
        CountryGUI;
        StatesGUI;
        Find;
        Data;
        File
    end
    
    methods (Access = private)
        
        function repeated(app) %This function removes repeated entry
            z=app.Country;
            y=length(z);
            i=1;
            j=1;
        while i<=y
         x(j)="";
         x(j)=z(i);
          i=i+counter(app,x(j),z);
        j=j+1;
        end
        app.CountryListBox.Items=cellstr(x);
        app.CountryListBox.Value=z(1);
        trace=find(x==string(app.CountryGUI));
        if ~isempty(trace)
         y=x(trace); 
         app.CountryListBox.Items=cellstr(y);
         app.CountryListBox.Value=y(1);
        end
        end
        function last=counter(~,x,z)
         a=z==x;
         a=find(a==1);
         if ~isempty(a)
          last=length(a);
           else
        last=1;
         end
        end
        
        function statedisplayer(app)
            z1=app.Country;
            z2=app.States;
            y=string(app.CountryListBox.Value);
            a=z1==y;
            z3=z2(a);
            app.StateorRegionListBox.Items=cellstr(z3);
            app.StateorRegionListBox.Value=z3(1);
            trace=find(z3==string(app.StatesGUI));
            if ~isempty(trace)
                z3=z3(trace);
                app.StateorRegionListBox.Items=cellstr(z3);
                app.StateorRegionListBox.Value=cellstr(z3(1));
            end
        end
        
        function finder(app)
            z1=string(app.CountryListBox.Value);
            z2=string(app.StateorRegionListBox.Value);
            z=app.Country;
            z3=find(z==z1);
            z5=app.States;
            z4=find(z5==z2);
            app.Find=intersect(z3,z4);
        end
        function  filemaker(app)
            matrix=string(app.Global(:,2));
            matrix=find(matrix=="");
            x=cell2mat(app.Global((2:end),(3:end)));
            y=zeros(1,568);
            z=[y;x];
            [x1,y1]=size(z);
            app.File=zeros(x1,y1);
            for i=1:(y1/2)
                   app.File(1,2*i-1)=sum(z(matrix,2*i-1));
                  app.File(1,2*i)=sum(z(matrix,2*i)); 
            end
            app.File((2:x1),:)=x;
        end
        
        function detector(app)
            sh=app.Find;
            app.File(sh,(1:end));
            z1=length(app.File(sh,(1:end)));
            app.Data=app.File(sh,(1:end));
            if app.DailyButton.Value
                for i=2:(z1/2)
                    app.Data(2*i-1)=app.File(sh,2*i-1)-app.File(sh,2*i-3);
                    app.Data(2*i)=app.File(sh,2*i)-app.File(sh,2*i-2);
                end
                app.Data(app.Data<0)=0;
            end
        end
        function plotting(app)
            x=datetime(2020,1,22,'Format','dd/MM'):datetime(2020,10,31);
            xlim(app.UIAxes,'manual');
            format long;
            if app.CasesButton.Value
                z=app.CasesButton.Text;
            elseif app.DeathButton.Value
                z=app.DeathButton.Text;
            elseif app.BothButton.Value
                z="Cases and Death";
            end
            if app.CumulativeButton.Value
                z2=app.CumulativeButton.Text;
            elseif app.DailyButton.Value
                z2=app.DailyButton.Text;
            end
            if floor(app.AverageofdaysSlider.Value) ==1
            z1=sprintf('%s Number of %s in %s states of the %s',z2,z...
                ,app.StateorRegionListBox.Value,app.CountryListBox.Value);
            elseif floor(app.AverageofdaysSlider.Value) ~=1
                z1=sprintf('%s Number of %s in %s states of the %s(%d-day mean)',...
                z2,z,app.StateorRegionListBox.Value,app.CountryListBox.Value,...
                floor(app.AverageofdaysSlider.Value));
            end
            title(app.UIAxes,z1);
            grid(app.UIAxes,"on");
            yyaxis(app.UIAxes,"left");
            h1=bar(app.UIAxes,x,app.Data(1:2:end));
            xtickformat(app.UIAxes,'MM/dd');
            app.UIAxes.YAxis(1).Exponent=0;
            yyaxis(app.UIAxes,"right");
            h2=plot(app.UIAxes,x,app.Data(2:2:end),'r');
            xtickformat(app.UIAxes,'MM/dd');
            app.UIAxes.YAxis(2).Exponent=0;
            if app.CasesButton.Value
              set(h2,'Visible','off');  
            end
            if app.DeathButton.Value
               set(h2,'Visible','on'); 
               set(h1,'Visible','off');
            end
        end
        
        function mean(app)
            x=floor(app.AverageofdaysSlider.Value);
            z1=length(app.Data);
            dum=zeros(1,z1);
            for i=1:(z1/2)
               if i<x
                   dum(2*i-1)=(sum(app.Data(2*i:-2:1))/i);
                   dum(2*i)=(sum(app.Data(2*i-1:-2:2))/i);
               else
                   dum(2*i)=(sum(app.Data(2*i:-2:2*i-x))/x);
                   dum(2*i-1)=(sum(app.Data(2*i-1:-2:2*i-1-x))/x);
               end
            end
            app.Data=dum;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load covid_data.mat covid_data;
            app.Global=covid_data;
            app.Country=string(app.Global(:,1));
            app.Country(1)="Global";
            app.CountryGUI="";
            app.States=string(app.Global(:,2));
            app.States(app.States=="")="All";
            app.States(1)="All";
            app.StatesGUI="";
            app.repeated();
            app.CountryListBox.Value="Global";
            app.statedisplayer();
            app.StateorRegionListBox.Value="All";
            app.BothButton.Value=true;
            app.DeathButton.Value=false;
            app.CasesButton.Value=false;
            app.CumulativeButton.Value=false;
            app.DailyButton.Value=true;
            app.finder();
            app.filemaker();
            app.detector();
            app.plotting();
            app.Find=1;
            app.mean();
        end

        % Value changed function: CountryListBox
        function CountryListBoxValueChanged(app, event)
            app.finder();
            app.detector();
            app.mean();
            app.plotting();
            app.statedisplayer();
        end

        % Value changed function: StateorRegionListBox
        function StateorRegionListBoxValueChanged(app, event)
            app.finder();
            app.detector();
            app.mean();
            app.plotting(); 
        end

        % Selection changed function: DatatoPlotButtonGroup
        function DatatoPlotButtonGroupSelectionChanged(app, event)
            app.plotting();
        end

        % Selection changed function: OptionButtonGroup
        function OptionButtonGroupSelectionChanged(app, event)
            app.detector();
            app.mean();
            app.plotting();
        end

        % Value changed function: AverageofdaysSlider
        function AverageofdaysSliderValueChanged(app, event)
            app.mean();
            app.plotting();
        end

        % Value changed function: SearchtheCountryEditField
        function SearchtheCountryEditFieldValueChanged(app, event)
            app.CountryGUI = app.SearchtheCountryEditField.Value;
            app.repeated();
            app.finder();
            app.detector();
            app.mean();
            app.plotting();
            app.statedisplayer();
        end

        % Value changed function: SearhthestatevalueEditField
        function SearhthestatevalueEditFieldValueChanged(app, event)
            app.StatesGUI = app.SearhthestatevalueEditField.Value;
            app.statedisplayer();
            app.finder();
            app.detector();
            app.mean();
            app.plotting();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.COVID19DataVisualisationUIFigure = uifigure('Visible', 'off');
            app.COVID19DataVisualisationUIFigure.Name = 'Nivaaran: COVID-1Data Visualisation(Data Source: Johns Hopkins University) ';
            app.CountryListBoxLabel = uilabel(app.COVID19DataVisualisationUIFigure);
            app.CountryListBoxLabel.Text = 'Country:';
            app.CountryListBox = uilistbox(app.COVID19DataVisualisationUIFigure);
            app.CountryListBox.Items = {};
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @CountryListBoxValueChanged, true);
            app.CountryListBox.Value = {};
            app.StateorRegionLabel = uilabel(app.COVID19DataVisualisationUIFigure);
            app.StateorRegionLabel.Text = {'State or'; 'Region:'};
            app.StateorRegionListBox = uilistbox(app.COVID19DataVisualisationUIFigure);
            app.StateorRegionListBox.Items = {};
            app.StateorRegionListBox.ValueChangedFcn = createCallbackFcn(app, @StateorRegionListBoxValueChanged, true);
            app.StateorRegionListBox.Value = {};
            app.AverageofdaysLabel = uilabel(app.COVID19DataVisualisationUIFigure);
            app.AverageofdaysLabel.Text = 'Average # of days:';
            app.AverageofdaysSlider = uislider(app.COVID19DataVisualisationUIFigure);
            app.AverageofdaysSlider.Limits = [1 15];
            app.AverageofdaysSlider.ValueChangedFcn = createCallbackFcn(app, @AverageofdaysSliderValueChanged, true);
            app.AverageofdaysSlider.Value = 1;
            app.OptionButtonGroup = uibuttongroup(app.COVID19DataVisualisationUIFigure);
            app.OptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionButtonGroupSelectionChanged, true);
            app.OptionButtonGroup.Title = 'Option';
            app.CumulativeButton = uiradiobutton(app.OptionButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.DailyButton = uiradiobutton(app.OptionButtonGroup);
            app.DailyButton.Text = 'Daily';
            app.DailyButton.Value = true;
            app.DatatoPlotButtonGroup = uibuttongroup(app.COVID19DataVisualisationUIFigure);
            app.DatatoPlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatatoPlotButtonGroupSelectionChanged, true);
            app.DatatoPlotButtonGroup.Title = 'Data to Plot:';
            app.CasesButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.DeathButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.DeathButton.Text = 'Death';
            app.BothButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.BothButton.Text = 'Both';
            app.BothButton.Value = true;
            app.SearchtheCountryEditField = uieditfield(app.COVID19DataVisualisationUIFigure, 'text');
            app.SearchtheCountryEditField.ValueChangedFcn = createCallbackFcn(app, @SearchtheCountryEditFieldValueChanged, true);
            app.SearhthestatevalueEditField = uieditfield(app.COVID19DataVisualisationUIFigure, 'text');
            app.SearhthestatevalueEditField.ValueChangedFcn = createCallbackFcn(app, @SearhthestatevalueEditFieldValueChanged, true);
            app.SearchLabel = uilabel(app.COVID19DataVisualisationUIFigure);
            app.SearchLabel.Text = 'Search';
            app.SearchLabel_2 = uilabel(app.COVID19DataVisualisationUIFigure);
            app.SearchLabel_2.Text = 'Search';
            app.UIAxes = uiaxes(app.COVID19DataVisualisationUIFigure);
            title(app.UIAxes, 'Title')
            % Show the figure after all components are created
            app.COVID19DataVisualisationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nivaaran

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.COVID19DataVisualisationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.COVID19DataVisualisationUIFigure)
        end
    end
end